classdef proyecto < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ProyectoSealesUIFigure          matlab.ui.Figure
        SuperponerACheckBox             matlab.ui.control.CheckBox
        GuardarenLabel                  matlab.ui.control.Label
        ButtonGroup                     matlab.ui.container.ButtonGroup
        BButton                         matlab.ui.control.RadioButton
        AButton                         matlab.ui.control.RadioButton
        GrabaraudioButton               matlab.ui.control.Button
        ReproducirBButton               matlab.ui.control.Button
        ReproducirAButton               matlab.ui.control.Button
        ReproducirSalidaButton          matlab.ui.control.Button
        ConfiguracindelaOperacinPanel   matlab.ui.container.Panel
        DesplazaralaizquierdaCheckBox   matlab.ui.control.CheckBox
        SealPrincipalSwitch             matlab.ui.control.Switch
        SealPrincipalSwitchLabel        matlab.ui.control.Label
        TipodeinterpoladoDropDown       matlab.ui.control.DropDown
        TipodeinterpoladoDropDownLabel  matlab.ui.control.Label
        FactordeOperacionSlider         matlab.ui.control.Slider
        FactordeOperacinSliderLabel     matlab.ui.control.Label
        OperacionKnob                   matlab.ui.control.DiscreteKnob
        OperacinKnobLabel               matlab.ui.control.Label
        EntradasAxes                    matlab.ui.control.UIAxes
        SalidaAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Operacion Operaciones = Operaciones.Convolucion
        Interpolado Interpolacion = Interpolacion.Cero
        RecorderA audiorecorder = audiorecorder
        RecorderB audiorecorder = audiorecorder
        GrabarA (1, 1) logical = 1
        SuperponerA (1,1) logical = 0
        OperarA (1, 1) logical = 1
        SignalA = zeros(1, 8000*3)
        SignalB = zeros(1, 8000*3)
        Salida = zeros(1, 8000*3)
        kAxisX = (0:8000*3-1) / 8000
        time = (0:8000*3-1) / 8000
        Factor = 5
    end
    
    methods (Access = private)
        
        function graficarEntradas(app)
            hold(app.EntradasAxes, "off");
            grid(app.EntradasAxes,'on');
            
            if app.SuperponerA
                plot(app.EntradasAxes, app.time, app.SignalB,...
                    'Color', '#66d9be');
                hold(app.EntradasAxes, "on");
                plot(app.EntradasAxes, app.time, app.SignalA,...
                    'Color', '#7d568c');
            else
                plot(app.EntradasAxes, app.time, app.SignalA,...
                    'Color', '#7d568c');
                hold(app.EntradasAxes, "on");
                plot(app.EntradasAxes, app.time, app.SignalB,...
                    'Color', '#66d9be');
            end
        end
        
        function graficarSalida(app)
            grid(app.SalidaAxes,'on');
            plot(app.SalidaAxes, app.time, app.Salida,...
                    'Color', '#ff5ebf');
        end
        
        function llamarOperacion(app)
            switch app.Operacion
                case Operaciones.Desplazar
                    
                case Operaciones.Reflejar
                    app.reflejar();
                case Operaciones.Atenuar
                    app.atenuar();
                case Operaciones.Sumar
                    app.sumar();
                case Operaciones.Convolucion
                    
                case Operaciones.Restar
                    app.restar();
                case Operaciones.Diezmar
                    
                case Operaciones.Interpolar
                    
                otherwise
                    app.amplificar();
            end
            
            app.graficarSalida();
        end
        
        function atenuar(app)
            if app.OperarA
                app.Salida = app.SignalA / app.Factor;
            else
                app.Salida = app.SignalB / app.Factor;
            end
        end
        
        function amplificar(app)
            if app.OperarA
                app.Salida = app.SignalA * app.Factor;
            else
                app.Salida = app.SignalB * app.Factor;
            end
        end
        
        function sumar(app)
            app.Salida = app.SignalA + app.SignalB;
        end
        
        function restar(app)
            if app.OperarA
                app.Salida = app.SignalA - app.SignalB;
            else
                app.Salida = app.SignalB - app.SignalA;
            end
        end
        
        function reflejar(app)
            if app.OperarA
                app.Salida = flip(app.SignalA);
            else
                app.Salida = flip(app.SignalB);
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GrabaraudioButton
        function GrabaraudioButtonPushed(app, event)
            window = msgbox('Grabando audio');
            
            if app.GrabarA
                recordblocking(app.RecorderA, 3);
                app.SignalA = getaudiodata(app.RecorderA);
            else
                recordblocking(app.RecorderB, 3);
                app.SignalB = getaudiodata(app.RecorderB);
            end
            
            close(window);
            app.graficarEntradas();
            app.llamarOperacion();
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            
            switch get(selectedButton, 'tag')
                case 'A'
                    app.GrabarA = true;
                otherwise
                    app.GrabarA = false;
            end
        end

        % Value changed function: SuperponerACheckBox
        function SuperponerACheckBoxValueChanged(app, event)
            isChecked = app.SuperponerACheckBox.Value;
            app.SuperponerA = logical(isChecked);
            app.graficarEntradas();
        end

        % Button pushed function: ReproducirAButton
        function ReproducirAButtonPushed(app, event)
            sound(app.SignalA);
        end

        % Button pushed function: ReproducirBButton
        function ReproducirBButtonPushed(app, event)
            sound(app.SignalA);
        end

        % Value changed function: OperacionKnob
        function OperacionKnobValueChanged(app, event)
            value = app.OperacionKnob.Value;
            
            switch value
                case 'Desplazar'
                    app.Operacion = Operaciones.Desplazar;
                case 'Reflejar'
                    app.Operacion = Operaciones.Reflejar;
                case 'Atenuar'
                    app.Operacion = Operaciones.Atenuar;
                case 'Sumar'
                    app.Operacion = Operaciones.Sumar;
                case 'Convolución'
                    app.Operacion = Operaciones.Convolucion;
                case 'Restar'
                    app.Operacion = Operaciones.Restar;
                case 'Diezmar'
                    app.Operacion = Operaciones.Diezmar;
                case 'Interpolar'
                    app.Operacion = Operaciones.Interpolar;
                otherwise
                    app.Operacion = Operaciones.Amplificar;
            end
            
            app.llamarOperacion();
        end

        % Value changed function: FactordeOperacionSlider
        function FactordeOperacionSliderValueChanging(app, event)
            changingValue = event.Value;
            app.Factor = changingValue;
            
            if app.Operacion == Operaciones.Desplazar ||...
                    app.Operacion == Operaciones.Atenuar ||...
                    app.Operacion == Operaciones.Amplificar ||...
                    app.Operacion == Operaciones.Diezmar ||...
                    app.Operacion == Operaciones.Interpolar
                app.llamarOperacion();
            end
        end

        % Value changed function: SealPrincipalSwitch
        function SealPrincipalSwitchValueChanged(app, event)
            value = app.SealPrincipalSwitch.Value;
            app.OperarA = value == 'A';
            app.llamarOperacion();
        end

        % Value changed function: TipodeinterpoladoDropDown
        function TipodeinterpoladoDropDownValueChanged(app, event)
            value = app.TipodeinterpoladoDropDown.Value;
            
            switch value
                case 'Cero'
                    app.Interpolado = Interpolacion.Cero;
                case 'Escalon'
                    app.Interpolado = Interpolacion.Escalon;
                otherwise
                    app.Interpolado = Interpolacion.Lineal;
            end
            
            if app.Operacion == Operaciones.Interpolar
                app.llamarOperacion();
            end
        end

        % Button pushed function: ReproducirSalidaButton
        function ReproducirSalidaButtonPushed(app, event)
            sound(app.Salida);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ProyectoSealesUIFigure = uifigure('Visible', 'off');
            app.ProyectoSealesUIFigure.Name = 'Proyecto Señales';
            app.SalidaAxes = uiaxes(app.ProyectoSealesUIFigure);
            title(app.SalidaAxes, 'Salida')
            xlabel(app.SalidaAxes, 'Tiempo')
            ylabel(app.SalidaAxes, 'Intensidad')
            app.EntradasAxes = uiaxes(app.ProyectoSealesUIFigure);
            title(app.EntradasAxes, 'Entradas')
            xlabel(app.EntradasAxes, 'Tiempo')
            ylabel(app.EntradasAxes, 'Intensidad')
            app.ConfiguracindelaOperacinPanel = uipanel(app.ProyectoSealesUIFigure);
            app.ConfiguracindelaOperacinPanel.Title = 'Configuración de la Operación';
            app.OperacinKnobLabel = uilabel(app.ConfiguracindelaOperacinPanel);
            app.OperacinKnobLabel.Text = 'Operación';
            app.OperacionKnob = uiknob(app.ConfiguracindelaOperacinPanel, 'discrete');
            app.OperacionKnob.Items = {'Desplazar', 'Reflejar', 'Atenuar', 'Sumar', 'Convolución', 'Restar', 'Diezmar', 'Interpolar', 'Amplificar'};
            app.OperacionKnob.ValueChangedFcn = createCallbackFcn(app, @OperacionKnobValueChanged, true);
            app.OperacionKnob.Value = 'Convolución';
            app.FactordeOperacinSliderLabel = uilabel(app.ConfiguracindelaOperacinPanel);
            app.FactordeOperacinSliderLabel.Text = 'Factor de Operación';
            app.FactordeOperacionSlider = uislider(app.ConfiguracindelaOperacinPanel);
            app.FactordeOperacionSlider.Limits = [0 50];
            app.FactordeOperacionSlider.ValueChangedFcn = createCallbackFcn(app, @FactordeOperacionSliderValueChanging, true);
            app.FactordeOperacionSlider.Value = 5;
            app.TipodeinterpoladoDropDownLabel = uilabel(app.ConfiguracindelaOperacinPanel);
            app.TipodeinterpoladoDropDownLabel.Text = 'Tipo de interpolado';
            app.TipodeinterpoladoDropDown = uidropdown(app.ConfiguracindelaOperacinPanel);
            app.TipodeinterpoladoDropDown.Items = {'Cero', 'Escalón', 'Lineal'};
            app.TipodeinterpoladoDropDown.ItemsData = {'Cero', 'Escalon', 'Lineal'};
            app.TipodeinterpoladoDropDown.ValueChangedFcn = createCallbackFcn(app, @TipodeinterpoladoDropDownValueChanged, true);
            app.TipodeinterpoladoDropDown.Value = 'Cero';
            app.SealPrincipalSwitchLabel = uilabel(app.ConfiguracindelaOperacinPanel);
            app.SealPrincipalSwitchLabel.Text = 'Señal Principal';
            app.SealPrincipalSwitch = uiswitch(app.ConfiguracindelaOperacinPanel, 'slider');
            app.SealPrincipalSwitch.Items = {'A', 'B'};
            app.SealPrincipalSwitch.ValueChangedFcn = createCallbackFcn(app, @SealPrincipalSwitchValueChanged, true);
            app.SealPrincipalSwitch.Value = 'A';
            app.DesplazaralaizquierdaCheckBox = uicheckbox(app.ConfiguracindelaOperacinPanel);
            app.DesplazaralaizquierdaCheckBox.Text = 'Desplazar a la izquierda';
            app.ReproducirSalidaButton = uibutton(app.ProyectoSealesUIFigure, 'push');
            app.ReproducirSalidaButton.ButtonPushedFcn = createCallbackFcn(app, @ReproducirSalidaButtonPushed, true);
            app.ReproducirSalidaButton.Text = 'Reproducir Salida';
            app.ReproducirAButton = uibutton(app.ProyectoSealesUIFigure, 'push');
            app.ReproducirAButton.ButtonPushedFcn = createCallbackFcn(app, @ReproducirAButtonPushed, true);
            app.ReproducirAButton.Text = 'Reproducir A';
            app.ReproducirBButton = uibutton(app.ProyectoSealesUIFigure, 'push');
            app.ReproducirBButton.ButtonPushedFcn = createCallbackFcn(app, @ReproducirBButtonPushed, true);
            app.ReproducirBButton.Text = 'Reproducir B';
            app.GrabaraudioButton = uibutton(app.ProyectoSealesUIFigure, 'push');
            app.GrabaraudioButton.ButtonPushedFcn = createCallbackFcn(app, @GrabaraudioButtonPushed, true);
            app.GrabaraudioButton.Text = 'Grabar audio';
            app.ButtonGroup = uibuttongroup(app.ProyectoSealesUIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.AButton = uiradiobutton(app.ButtonGroup);
            app.AButton.Tag = 'A';
            app.AButton.Text = 'A';
            app.AButton.Value = true;
            app.BButton = uiradiobutton(app.ButtonGroup);
            app.BButton.Tag = 'B';
            app.BButton.Text = 'B';
            app.GuardarenLabel = uilabel(app.ProyectoSealesUIFigure);
            app.GuardarenLabel.Text = 'Guardar en:';
            app.SuperponerACheckBox = uicheckbox(app.ProyectoSealesUIFigure);
            app.SuperponerACheckBox.ValueChangedFcn = createCallbackFcn(app, @SuperponerACheckBoxValueChanged, true);
            app.SuperponerACheckBox.Text = 'Superponer A';
            % Show the figure after all components are created
            app.ProyectoSealesUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = proyecto

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ProyectoSealesUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ProyectoSealesUIFigure)
        end
    end
end